classdef home_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        CitationsTextArea              matlab.ui.control.TextArea
        CitationsTextAreaLabel         matlab.ui.control.Label
        ConclusionsandInferencesTextArea  matlab.ui.control.TextArea
        ConclusionsandInferencesTextAreaLabel  matlab.ui.control.Label
        SupportVectorMachineSVMButton  matlab.ui.control.Button
        RandomForestRFButton           matlab.ui.control.Button
        KMeansClusteringButton         matlab.ui.control.Button
        HeartDiseaseLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        SVMModel %stores the SVM model 
        RFModel %stores the RF model 
        KMeansModel %stores the K means model 
        Data  % Cleaveland Data - The data on which file is trained 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KMeansClusteringButton
        function KMeansClusteringButtonPushed(app, event)
            km
            close(app.UIFigure)
        end

        % Button pushed function: RandomForestRFButton
        function RandomForestRFButtonPushed(app, event)
            rf
            close(app.UIFigure)
        end

        % Button pushed function: SupportVectorMachineSVMButton
        function SupportVectorMachineSVMButtonPushed(app, event)
            svm
            close(app.UIFigure)
        end

        % Callback function: not associated with a component
        function LoadDataButtonPushed(app, event)
            data = readtable('processed.cleveland.data','FileType','text','ReadRowNames',false);
        end

        % Callback function: not associated with a component
        function testdataplotButtonPushed(app, event)
            %% just a test
            data = readtable('processed.cleveland.data','FileType','text','ReadRowNames',false);
            plot(app.UIAxes,data{:,1},data{:,2})
            title(app.UIAxes,"Proof of concept")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.HeartDiseaseLabel = uilabel(app.Panel);
            app.HeartDiseaseLabel.Text = 'Heart Disease';
            app.KMeansClusteringButton = uibutton(app.Panel, 'push');
            app.KMeansClusteringButton.ButtonPushedFcn = createCallbackFcn(app, @KMeansClusteringButtonPushed, true);
            app.KMeansClusteringButton.Text = 'K-Means Clustering';
            app.RandomForestRFButton = uibutton(app.Panel, 'push');
            app.RandomForestRFButton.ButtonPushedFcn = createCallbackFcn(app, @RandomForestRFButtonPushed, true);
            app.RandomForestRFButton.Text = 'Random Forest(RF)';
            app.SupportVectorMachineSVMButton = uibutton(app.Panel, 'push');
            app.SupportVectorMachineSVMButton.ButtonPushedFcn = createCallbackFcn(app, @SupportVectorMachineSVMButtonPushed, true);
            app.SupportVectorMachineSVMButton.Text = 'Support Vector Machine(SVM)';
            app.ConclusionsandInferencesTextAreaLabel = uilabel(app.Panel);
            app.ConclusionsandInferencesTextAreaLabel.Text = 'Conclusions and Inferences';
            app.ConclusionsandInferencesTextArea = uitextarea(app.Panel);
            app.ConclusionsandInferencesTextArea.Editable = 'off';
            app.CitationsTextAreaLabel = uilabel(app.Panel);
            app.CitationsTextAreaLabel.Text = 'Citations';
            app.CitationsTextArea = uitextarea(app.Panel);
            app.CitationsTextArea.Editable = 'off';
            app.CitationsTextArea.Value = {'UCI Machine Learning Repository. "Cleveland Heart Disease Data Set." UCI Repository, https://archive.ics.uci.edu/ml/datasets/Heart+Disease.'; 'Detrano, R., et al. "Cleveland Heart Disease Data Set." UCI Machine Learning Repository, 1988. '; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = home_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end